classdef IsiGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AcquisitionPanel           matlab.ui.container.Panel
        ConnectCtrlButton          matlab.ui.control.Button
        CamerastatusPanel          matlab.ui.container.Panel
        PacketDelayLabel           matlab.ui.control.Label
        PacketDelayButton          matlab.ui.control.Button
        FrameRateLabel             matlab.ui.control.Label
        FrameRateButton            matlab.ui.control.Button
        CamStatusButton            matlab.ui.control.Button
        CamParTable                matlab.ui.control.Table
        RecModeLamp                matlab.ui.control.Lamp
        ReadytorecordLampLabel     matlab.ui.control.Label
        CamConLamp                 matlab.ui.control.Lamp
        CameraconnectionLampLabel  matlab.ui.control.Label
        PreviewPanel               matlab.ui.container.Panel
        HistogramButton            matlab.ui.control.Button
        CmapButtonGroup            matlab.ui.container.ButtonGroup
        ColorButton                matlab.ui.control.RadioButton
        GrayscaleButton            matlab.ui.control.RadioButton
        StartPreviewButton         matlab.ui.control.StateButton
        DeleteROIButton            matlab.ui.control.Button
        DefineROIButton            matlab.ui.control.Button
        SaveSnapButton             matlab.ui.control.Button
        GetSnapButton              matlab.ui.control.Button
        CameraPanel                matlab.ui.container.Panel
        RecModeButton              matlab.ui.control.Button
        CamConButton               matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        fRate=16; %camera rate, initialized to 16fps
        pDelay; %packet delay
    end
    
    methods (Access = private)
        
        function results = customWait(~,hROI)
            
            %listen for mouse clicksO
            l=addlistener(hROI,'ROIClicked',@clickCallback);
            
            %block further execution
            uiwait;
            %remove listener
            delete(l);
            %return position
            results=hROI.Position;
                       
            % double click to resume and accept roi
            function clickCallback(~,evt)
                if strcmp(evt.SelectionType,'double')
                    uiresume;
                end
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            parname{1}='CUR_HRZ_SZE';
            parname{2}='CUR_VER_SZE';
            parname{3}='TriggerType';
            parname{4}='TriggerMode';
            % parname{7}='MaxExposure';
            
            parval=cell(size(parname));
            
            t=table(parname',parval');
            app.CamParTable.Data=t;

          
            % set app title
            app.UIFigure.Name = 'OptImg';

        end

        % Value changed function: CamConButton
        function CamConButtonValueChanged(app, event)
            global cam
            
            value = app.CamConButton.Value;
            if value==0
                %executed when pushing 'disconnect'
                %disconnect camera
                % global cam;
                
                if(exist('cam', 'var'))
                    src = getselectedsource(cam);
                    delete(cam);
                    clear cam;
                    clear src;
                    
                    t=app.CamParTable.Data;
                    t.Var2=cell(height(t),1);
                    app.CamParTable.Data=t;
                end
                
                %update GUI
                app.CamConButton.Text='Connect camera';
                app.CamConLamp.Color=[1,0,0];
                app.RecModeLamp.Color=[1,0,0];
                app.RecModeButton.Enable=0;
                app.CamStatusButton.Enable=0;
                app.FrameRateButton.Enable=0;
                app.FrameRateLabel.Text='NaN';
                app.PacketDelayButton.Enable=0;
                app.PacketDelayLabel.Text='NaN';
                app.StartPreviewButton.Enable=0;
                app.CmapButtonGroup.Enable = 0;
                app.GetSnapButton.Enable=0;
                app.HistogramButton.Enable=0;
                app.DefineROIButton.Enable=0;
                app.DeleteROIButton.Enable=0;
            else
                %executed when pushing 'connect'
                %connect camera
                app.UIFigure.Pointer='watch';
                cam = videoinput('gige', 1);
                
                if exist('cam')
                    src = getselectedsource(cam);
                    stop(cam)
                    
                    % make sure Jumbo Frames are set to 9k in the GigE NIC adapter settings
                    src.PacketSize = 9000;
                    src.PacketDelay = 112;  % used CalculatePacketDelay.m

                    %initialize without triggers and full field
                    set(src, 'TriggerMode', 'Off');
                    triggerconfig(cam,'immediate','none','none');
                    fullSize=cam.VideoResolution;
                    cam.ROIPosition=[0 0 fullSize(1) fullSize(2)];
                    
                    % activate strobe1
                    set(src, 'StdStrobe1Mode', 'EachFrame');

                    % callback for framesAcquired
                    cam.FramesAcquiredFcn = {@camSaveFramesCb, cam};
                    cam.FramesAcquiredFcnCount = 100;
                    cam.TriggerFcn = 'camTriggerOccurred';

                    %get camera parameters
                    t=app.CamParTable.Data;
                    for i=1:height(t)
                        t.Var2{i}=num2str(src.(t.Var1{i}));
                    end
                    app.CamParTable.Data=t;
                    
                    %turn some of the warnings off
                    warning('off','imaq:gige:adaptorErrorPropSet');
                    warning('off','imaq:gige:adaptorSetROIModified');
                    
                    % JK Might not want this set to on!
                    cam.IgnoreDroppedFrames = 'on';

                    %get initial frame rate and packet delays
                    app.fRate = CalculateFrameRate(cam,20);
                    app.FrameRateLabel.Text=num2str(app.fRate);
                    app.FrameRateButton.Enable=1;

                    app.pDelay = CalculatePacketDelay(cam, app.fRate);
                    app.PacketDelayButton.Enable=1;
                    app.PacketDelayLabel.Text=num2str(app.pDelay);

                    %turn buttons on
                    app.CamConLamp.Color=[0,1,0];
                    app.RecModeButton.Enable=1;
                    app.CamStatusButton.Enable=1;
                   
                    app.StartPreviewButton.Enable=1;
                    app.GetSnapButton.Enable=1;
                    app.DefineROIButton.Enable=1;
                    app.DeleteROIButton.Enable=1;
                end
                
                app.CamConButton.Text='Disconnect camera';
                app.UIFigure.Pointer='arrow';

            end
        end

        % Button pushed function: RecModeButton
        function RecModeButtonPushed(app, event)
            %hack - the camera sometimes doesn't set exposure correctly
            %when switchting trigger modes, and then nothing works
            %we switch modes here before recording, and keep doing so until
            %exposure correct; important: nrframes needs to be set to 1,
            %otherwise this hack doesn't work
            
            global cam
            
            src = getselectedsource(cam);

            set(src,'TriggerMode','On');
            cam.FramesPerTrigger = 1;
            set(src,'TriggerNumFrames',1);
            set(src,'TriggerType','Standard');
            triggerconfig(cam,'hardware','DeviceSpecific','DeviceSpecific');
        
            while src.MaxExposure==0
                disp(src.MaxExposure)
                set(src, 'TriggerMode', 'Off');
                triggerconfig(cam,'immediate','none','none');
                set(src,'TriggerMode','On');
                cam.FramesPerTrigger = 1;
                set(src,'TriggerNumFrames',1);
                set(src,'TriggerType','Standard');
                triggerconfig(cam,'hardware','DeviceSpecific','DeviceSpecific');
            end
            
            % callback for framesAcquired
            cam.FramesAcquiredFcn = {@camSaveFramesCb, cam};
            cam.FramesAcquiredFcnCount = 100;  % set this to a largish value for now
            cam.FramesPerTrigger = cam.FramesAcquiredFcnCount;
            src.TriggerNumFrames = cam.FramesPerTrigger;

            % a callback to show a trigger was detected.  Not necessary ...
            cam.TriggerFcn = 'camTriggerOccurred';
            
            % activate strobe
            set(src,'StdStrobe1Mode','EachFrame');
            
            %update gui
            t=app.CamParTable.Data;
            for i=1:height(t)
                if strcmp(t.Var1{i},'FrameRate')
                    frate = app.getFrameRate(app,cam,20);
                    t.Var2{i}=frate;
                else
                    t.Var2{i}=num2str(src.(t.Var1{i}));
                end
            end
            app.CamParTable.Data=t;
            app.RecModeLamp.Color=[0,1,0];
            
        end

        % Button pushed function: CamStatusButton
        function CamStatusButtonPushed(app, event)
            global cam
            
            src = getselectedsource(cam);
            
            %update camera parameters
            t=app.CamParTable.Data;
            for i=1:height(t)
                t.Var2{i}=num2str(src.(t.Var1{i}));
            end
            app.CamParTable.Data=t;
            
            %check whether we can actually record
            if src.MaxExposure==0
                app.RecModeLamp.Color=[1,0,0];
            end
            if ~strcmp(src.TriggerMode,'On')
                app.RecModeLamp.Color=[1,0,0];
            end
            
        end

        % Button pushed function: FrameRateButton
        function FrameRateButtonPushed(app, event)
            global cam;
            app.UIFigure.Pointer='watch';
            app.fRate = CalculateFrameRate(cam,20);
            app.FrameRateLabel.Text=num2str(app.fRate);
            app.UIFigure.Pointer='arrow';
        end

        % Button pushed function: PacketDelayButton
        function PacketDelayButtonPushed(app, event)
            global cam;
            app.UIFigure.Pointer='watch';
            app.pDelay = CalculatePacketDelay(cam, app.fRate);
            app.PacketDelayLabel.Text=num2str(app.pDelay);
            app.UIFigure.Pointer='arrow';
        end

        % Value changed function: StartPreviewButton
        function PreviewButtonValueChanged(app, event)
            global cam camFigure
            
            value = app.StartPreviewButton.Value;
            
            if value==1 %run preview
                %set camera correctly
                src = getselectedsource(cam);
                set(src, 'TriggerMode', 'Off');
                triggerconfig(cam,'immediate','none','none');
                imgSize=cam.ROIPosition;
                
                %open figure and plot
                camFigure = figure('Name','Preview','NumberTitle','off','CloseRequestFcn','');
                hImage=image(axes(camFigure),zeros(imgSize(4),imgSize(3)));
                app.StartPreviewButton.Text = "Stop Preview";

                preview(cam,hImage);
                
                %enable colormap selection
                app.CmapButtonGroup.Enable = 1;
                app.GrayscaleButton.Enable=1;
                app.GrayscaleButton.Value = 1;
                app.ColorButton.Enable=1;
                app.HistogramButton.Enable=1;
                %disable everything else
            else %stop preview
                stoppreview(cam);
                if isvalid(camFigure)
                    delete(camFigure);
                end
                
                %disable colormap selection
                app.CmapButtonGroup.Enable = 0;
                app.GrayscaleButton.Enable=0;
                app.ColorButton.Enable=0;
                app.HistogramButton.Enable=0;
                app.StartPreviewButton.Text = "Start Preview";

            end
            
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            global cam;
            
            snapImg = getsnapshot(cam);
            figure('Name','Value distribution','NumberTitle','off');
            histogram(snapImg(:));
        end

        % Selection changed function: CmapButtonGroup
        function CmapSelectionChanged(app, event)
            global camFigure
            
            selectedButton = app.CmapButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Color')
                colormap(camFigure,jet(256));
            elseif strcmp(selectedButton.Text,'Grayscale')
                colormap(camFigure,gray(256));
            end
        end

        % Button pushed function: DefineROIButton
        function DefineROIButtonPushed(app, event)
            global cam camFigure;
            
            %disable all buttons to avoid confusion
            app.GrayscaleButton.Enable=0;
            app.ColorButton.Enable=0;
            
            %set camera correctly
            src = getselectedsource(cam);
            set(src, 'TriggerMode', 'Off');
            triggerconfig(cam,'immediate','none','none');
            currentROI=cam.ROIPosition;
            
            %open figure and plot
            camFigure = figure('Name','Select ROI','NumberTitle','off');
            hImage=image(axes(camFigure),zeros(currentROI(4),currentROI(3)));
            preview(cam,hImage);
            
            %draw rectangle - this blocks execution until double click;
            %depens on function clickCallback
            h = drawrectangle;
          
            pos=customWait(app,h);
            
            close(camFigure);
            
            %round and make sure we stay in limits; pos: xmin ymin width height
            %roiposition format: xoffset yoffset width height
            %xoffset and yoffset are not included
            app.UIFigure.Pointer='watch';
            fullSize=cam.VideoResolution;
            
            pos=round(pos);
            pos(1)=max(pos(1)-1+currentROI(1),0);
            pos(2)=max(pos(2)-1+currentROI(2),0);
            pos(3)=min(pos(3),fullSize(1)-pos(1));
            pos(4)=min(pos(4),fullSize(2)-pos(2));
            
            %update camera
            cam.ROIPosition=pos;
            pause(1)
            
            %update parameter table
            t=app.CamParTable.Data;
            for i=1:height(t)
                t.Var2{i}=num2str(src.(t.Var1{i}));
            end
            app.CamParTable.Data=t;
            
            
            %show snapshot
            snapImg = getsnapshot(cam);
            figure('Name','Imaging region','NumberTitle','off');
            imshow(snapImg);
            
            %determine updated frame rate and packet delay
            app.fRate = CalculateFrameRate(cam,20);
            app.FrameRateLabel.Text=num2str(app.fRate);

            app.pDelay = CalculatePacketDelay(cam, app.fRate);
            app.PacketDelayLabel.Text=num2str(app.pDelay);

            %turn buttons back on
            app.DeleteROIButton.Enable=1;
            app.GrayscaleButton.Enable=1;
            app.ColorButton.Enable=1;
            app.UIFigure.Pointer='arrow';
        end

        % Button pushed function: DeleteROIButton
        function DeleteROIButtonPushed(app, event)
            global cam;
            
            app.UIFigure.Pointer='watch';

            src = getselectedsource(cam);
            
            fullSize=cam.VideoResolution;
            cam.ROIPosition=[0 0 fullSize(1) fullSize(2)];
            
            %show snapshot
            snapImg = getsnapshot(cam);
            figure('Name','Imaging region','NumberTitle','off');
            imshow(snapImg);

            %update camera parameters
            pause(1)
            t=app.CamParTable.Data;
            for i=1:height(t)
                t.Var2{i}=num2str(src.(t.Var1{i}));
            end
            app.CamParTable.Data=t;

            %determine updated frame rate and packet delay
            app.fRate = CalculateFrameRate(cam,20);
            app.FrameRateLabel.Text=num2str(app.fRate);

            app.pDelay = CalculatePacketDelay(cam, app.fRate);
            app.PacketDelayLabel.Text=num2str(app.pDelay);

            app.UIFigure.Pointer='arrow';
        end

        % Button pushed function: GetSnapButton
        function GetSnapButtonPushed(app, event)
            global cam camFigure;
            src = getselectedsource(cam);
  
            % switch trigger modes if needed
            trigger = src.TriggerSource;

            if trigger == "External"
                set(src, 'TriggerMode', 'Off');
                src.TriggerSource = "Software";
            end   
   

            snapImg = getsnapshot(cam);
            camFigure=figure('Name','Snapshot','NumberTitle','off');
            %hImage=image(axes(camFigure));
            imshow(snapImg);
            
            app.SaveSnapButton.Enable=1;

            % restore trigger mode
            set(src, 'TriggerMode', 'Off');
            src.TriggerSource = trigger;
            set(src, 'TriggerMode', 'On');

        end

        % Button pushed function: SaveSnapButton
        function SaveSnapButtonPushed(app, event)
            global camFigure;
            
            if isvalid(camFigure)
                snapImg=getimage(camFigure);
                
                [file,path]=uiputfile('*.tif');
                if ~isequal(file,0) && ~isequal(path,0)
                    filename=fullfile(path,file);
                    imwrite(snapImg,filename);
                end
                
                app.SaveSnapButton.Enable=0;
                close(camFigure);
            else
                uialert(app.UIFigure,'No snapshot for saving','Error')
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %global UDPport
            %flush(UDPport);            
            %clear global UDPport;
            close all;

            if(exist('cam', 'var'))
                delete(cam);
                clear cam;
                clear src;
            end
            delete(app);
            
        end

        % Button pushed function: ConnectCtrlButton
        function ConnectCtrlButtonPushed(app, event)
            %global UDPport
            
            %udp connection to stimulus control 
            %UDPport = udpport("IPV4",'LocalPort',8000);
            %set(UDPport, 'RemotePort', 9000);

            %set(UDPport, 'InputBufferSize', 1024)
            %set(UDPport, 'OutputBufferSize', 1024)
            
            
            %Establish udp port event callback criterion
            %UDPport.BytesAvailableFcnMode = 'Terminator';
            %UDPport.configureCallback("terminator",@camPldapsCb);
            
            %UDPport.Terminator = '~'; %Magic number to identify request from Stimulus ('c' as a string)
            %UDPport.configureTerminator(126);
               
            % fopen(UDPport);
            %stat=get(UDPport, 'Status');
           
            %if ~strcmp(stat, 'open')
            %    disp([' Trouble opening connection to camera computer; cannot proceed']);
            %    UDPport.udpHandle=[];
            %    return;
            %end
            
            %disp('UDP initialized');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CameraPanel = uipanel(app.UIFigure);
            app.CameraPanel.Title = 'Camera';
            app.CamConButton = uibutton(app.CameraPanel, 'state');
            app.CamConButton.ValueChangedFcn = createCallbackFcn(app, @CamConButtonValueChanged, true);
            app.CamConButton.Text = 'Connect camera';
            app.RecModeButton = uibutton(app.CameraPanel, 'push');
            app.RecModeButton.ButtonPushedFcn = createCallbackFcn(app, @RecModeButtonPushed, true);
            app.RecModeButton.Enable = 'off';
            app.RecModeButton.Text = 'Initialize recording mode';
            app.PreviewPanel = uipanel(app.UIFigure);
            app.PreviewPanel.Title = 'Preview';
            app.GetSnapButton = uibutton(app.PreviewPanel, 'push');
            app.GetSnapButton.ButtonPushedFcn = createCallbackFcn(app, @GetSnapButtonPushed, true);
            app.GetSnapButton.Enable = 'off';
            app.GetSnapButton.Text = 'Get snapshot';
            app.SaveSnapButton = uibutton(app.PreviewPanel, 'push');
            app.SaveSnapButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSnapButtonPushed, true);
            app.SaveSnapButton.Enable = 'off';
            app.SaveSnapButton.Text = 'Save snapshot';
            app.DefineROIButton = uibutton(app.PreviewPanel, 'push');
            app.DefineROIButton.ButtonPushedFcn = createCallbackFcn(app, @DefineROIButtonPushed, true);
            app.DefineROIButton.Enable = 'off';
            app.DefineROIButton.Text = 'Define ROI';
            app.DeleteROIButton = uibutton(app.PreviewPanel, 'push');
            app.DeleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIButtonPushed, true);
            app.DeleteROIButton.Enable = 'off';
            app.DeleteROIButton.Text = 'Delete ROI';
            app.StartPreviewButton = uibutton(app.PreviewPanel, 'state');
            app.StartPreviewButton.ValueChangedFcn = createCallbackFcn(app, @PreviewButtonValueChanged, true);
            app.StartPreviewButton.Enable = 'off';
            app.StartPreviewButton.Text = 'Start Preview';
            app.CmapButtonGroup = uibuttongroup(app.PreviewPanel);
            app.CmapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CmapSelectionChanged, true);
            app.CmapButtonGroup.Enable = 'off';
            app.CmapButtonGroup.Title = 'Colormap';
            app.GrayscaleButton = uiradiobutton(app.CmapButtonGroup);
            app.GrayscaleButton.Enable = 'off';
            app.GrayscaleButton.Text = 'Grayscale';
            app.GrayscaleButton.Value = true;
            app.ColorButton = uiradiobutton(app.CmapButtonGroup);
            app.ColorButton.Enable = 'off';
            app.ColorButton.Text = 'Color';
            app.HistogramButton = uibutton(app.PreviewPanel, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Enable = 'off';
            app.HistogramButton.Text = 'Histogram';
            app.CamerastatusPanel = uipanel(app.UIFigure);
            app.CamerastatusPanel.Title = 'Camera status';
            app.CameraconnectionLampLabel = uilabel(app.CamerastatusPanel);
            app.CameraconnectionLampLabel.Text = 'Camera connection:';
            app.CamConLamp = uilamp(app.CamerastatusPanel);
            app.ReadytorecordLampLabel = uilabel(app.CamerastatusPanel);
            app.ReadytorecordLampLabel.Text = 'Ready to record:';
            app.RecModeLamp = uilamp(app.CamerastatusPanel);
            app.CamParTable = uitable(app.CamerastatusPanel);
            app.CamParTable.ColumnName = {'Parameter'; 'Value'};
            app.CamParTable.RowName = {};
            app.CamStatusButton = uibutton(app.CamerastatusPanel, 'push');
            app.CamStatusButton.ButtonPushedFcn = createCallbackFcn(app, @CamStatusButtonPushed, true);
            app.CamStatusButton.Enable = 'off';
            app.CamStatusButton.Text = 'Check status';
            app.FrameRateButton = uibutton(app.CamerastatusPanel, 'push');
            app.FrameRateButton.ButtonPushedFcn = createCallbackFcn(app, @FrameRateButtonPushed, true);
            app.FrameRateButton.Text = 'Get frame rate';
            app.FrameRateLabel = uilabel(app.CamerastatusPanel);
            app.FrameRateLabel.Text = 'NaN';
            app.PacketDelayButton = uibutton(app.CamerastatusPanel, 'push');
            app.PacketDelayButton.ButtonPushedFcn = createCallbackFcn(app, @PacketDelayButtonPushed, true);
            app.PacketDelayButton.Text = 'Get packet delay';
            app.PacketDelayLabel = uilabel(app.CamerastatusPanel);
            app.PacketDelayLabel.Text = 'NaN';
            app.AcquisitionPanel = uipanel(app.UIFigure);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.ConnectCtrlButton = uibutton(app.AcquisitionPanel, 'push');
            app.ConnectCtrlButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectCtrlButtonPushed, true);
            app.ConnectCtrlButton.Text = 'Connect to control';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IsiGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end